classdef fuzzy_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RatingEditField          matlab.ui.control.EditField
        RatingEditFieldLabel     matlab.ui.control.Label
        RateButton               matlab.ui.control.Button
        PelayananEditField       matlab.ui.control.EditField
        PelayananEditFieldLabel  matlab.ui.control.Label
        MakananEditField         matlab.ui.control.EditField
        MakananEditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RateButton
        function RateButtonPushed(app, event)
            makanan = str2double(app.MakananEditField.Value);
            pelayanan = str2double(app.PelayananEditField.Value);
            
            fis = readfis('sistem_fuzzy');
            result = evalfis(fis, [makanan pelayanan]);
            
            app.RatingEditField.Value = num2str(result);
            if result < 3
                msgbox('Rating Jelek');
            elseif result <= 7
                msgbox('Rating Sedang');
            else
                msgbox('Rating Bagus');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MakananEditFieldLabel = uilabel(app.UIFigure);
            app.MakananEditFieldLabel.Text = 'Makanan';
            app.MakananEditField = uieditfield(app.UIFigure, 'text');
            app.MakananEditField.Tag = '1';
            app.PelayananEditFieldLabel = uilabel(app.UIFigure);
            app.PelayananEditFieldLabel.Text = 'Pelayanan';
            app.PelayananEditField = uieditfield(app.UIFigure, 'text');
            app.PelayananEditField.Tag = '2';
            app.RateButton = uibutton(app.UIFigure, 'push');
            app.RateButton.ButtonPushedFcn = createCallbackFcn(app, @RateButtonPushed, true);
            app.RateButton.Text = 'Rate';
            app.RatingEditFieldLabel = uilabel(app.UIFigure);
            app.RatingEditFieldLabel.Text = 'Rating';
            app.RatingEditField = uieditfield(app.UIFigure, 'text');
            app.RatingEditField.Tag = '3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fuzzy_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end