classdef konversi_satuan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        CMSlider       matlab.ui.control.Slider
        CMSliderLabel  matlab.ui.control.Label
        MSlider        matlab.ui.control.Slider
        MSliderLabel   matlab.ui.control.Label
        EditField_2    matlab.ui.control.EditField
        EditField      matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            x = str2double(app.EditField.Value);
            if ~isnan(x)
                app.MSlider.Value = x;
                app.CMSlider.Value = x * 100;
                app.EditField_2.Value = num2str(x * 100);
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            x = str2double(app.EditField_2.Value);
            if ~isnan(x)
                app.CMSlider.Value = x;
                app.MSlider.Value = x / 100;
                app.EditField.Value = num2str(x / 100);
            end
        end

        % Value changed function: MSlider
        function MSliderValueChanged(app, event)
            x = app.MSlider.Value;
            app.EditField.Value = num2str(x);
            app.CMSlider.Value = x * 100;
            app.EditField_2.Value = num2str(x * 100);
        end

        % Value changed function: CMSlider
        function CMSliderValueChanged(app, event)
            x = app.CMSlider.Value;
            app.EditField_2.Value = num2str(x);
            app.MSlider.Value = x / 100;
            app.EditField.Value = num2str(x / 100);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tag = '1';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Tag = '2';
            app.MSliderLabel = uilabel(app.UIFigure);
            app.MSliderLabel.Text = 'M';
            app.MSlider = uislider(app.UIFigure);
            app.MSlider.Limits = [0 10];
            app.MSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.MSlider.Tag = '1';
            app.CMSliderLabel = uilabel(app.UIFigure);
            app.CMSliderLabel.Text = 'CM';
            app.CMSlider = uislider(app.UIFigure);
            app.CMSlider.Limits = [0 1000];
            app.CMSlider.ValueChangedFcn = createCallbackFcn(app, @CMSliderValueChanged, true);
            app.CMSlider.Tag = '2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = konversi_satuan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end