classdef plot_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TutupButton           matlab.ui.control.Button
        HapusButton           matlab.ui.control.Button
        PlotButton            matlab.ui.control.Button
        RangeEditField        matlab.ui.control.EditField
        RangeEditFieldLabel   matlab.ui.control.Label
        FungsiEditField       matlab.ui.control.EditField
        FungsiEditFieldLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            f = app.FungsiEditField.Value;      % contoh: 'x.^2+3'
            r = str2num(app.RangeEditField.Value); %#ok<ST2NM> contoh: '[0 10]'
        
        if isempty(r) || numel(r) ~= 2
            uialert(app.UIFigure, 'Range harus seperti [0 10]', 'Input salah');
            return;
        end
        
        cla(app.UIAxes)
        
        fh = str2func(['@(x)' f]);
        fplot(app.UIAxes, fh, r);
        
        grid(app.UIAxes, 'on')
        end

        % Button pushed function: HapusButton
        function HapusButtonPushed(app, event)
            app.FungsiEditField.Value = '';
            app.RangeEditField.Value = '';
            cla(app.UIAxes)
        end

        % Button pushed function: TutupButton
        function TutupButtonPushed(app, event)
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GRAFIK FUNGSI')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FungsiEditFieldLabel = uilabel(app.UIFigure);
            app.FungsiEditFieldLabel.Text = 'Fungsi';
            app.FungsiEditField = uieditfield(app.UIFigure, 'text');
            app.FungsiEditField.Tag = '1';
            app.RangeEditFieldLabel = uilabel(app.UIFigure);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.UIFigure, 'text');
            app.RangeEditField.Tag = '2';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.HapusButton = uibutton(app.UIFigure, 'push');
            app.HapusButton.ButtonPushedFcn = createCallbackFcn(app, @HapusButtonPushed, true);
            app.HapusButton.Text = 'Hapus';
            app.TutupButton = uibutton(app.UIFigure, 'push');
            app.TutupButton.ButtonPushedFcn = createCallbackFcn(app, @TutupButtonPushed, true);
            app.TutupButton.Text = 'Tutup';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end