classdef transfourier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Button            matlab.ui.control.Button
        EditField_2       matlab.ui.control.EditField
        EditField_2Label  matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        EditFieldLabel    matlab.ui.control.Label
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            f1 = str2double(app.EditField.Value);
            f2 = str2double(app.EditField_2.Value);

            t = 0:0.001:1;
            y1 = sin(2*pi*f1*t);
            y2 = sin(2*pi*f2*t);
            y3 = y1 + y2;

            Fs = 1000;
            L = length(y3);

            Y = fft(y3);
            f = (-L/2:L/2-1)*(Fs/L);

            plot(app.UIAxes, t, y3);

            spectrum = fftshift(Y);
            plot(app.UIAxes2, f, abs(spectrum)/L);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transfourier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end